classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        INPUTOUTPUTFILESTab             matlab.ui.container.Tab
        INPUTFILESPanel                 matlab.ui.container.Panel
        Button                          matlab.ui.control.Button
        RinexObservationFileEditFieldLabel  matlab.ui.control.Label
        ObsEditField                    matlab.ui.control.EditField
        OrbitfileButtonGroup            matlab.ui.container.ButtonGroup
        Button_2                        matlab.ui.control.Button
        RinexNavigationFileEditFieldLabel  matlab.ui.control.Label
        NavEditField                    matlab.ui.control.EditField
        BroadcastCheckBox               matlab.ui.control.CheckBox
        SP3CheckBox                     matlab.ui.control.CheckBox
        AntexFileCheckBox               matlab.ui.control.CheckBox
        Label                           matlab.ui.control.Label
        EditField                       matlab.ui.control.EditField
        Button_3                        matlab.ui.control.Button
        IonosphereSourceButtonGroup     matlab.ui.container.ButtonGroup
        RinexNavigationFileEditField_2Label  matlab.ui.control.Label
        IonFileEditField                matlab.ui.control.EditField
        Button_4                        matlab.ui.control.Button
        BroadcastCheckBox_2             matlab.ui.control.CheckBox
        IONEXCheckBox                   matlab.ui.control.CheckBox
        OUTPUTFILESPanel                matlab.ui.container.Panel
        EditField_2                     matlab.ui.control.EditField
        Button_5                        matlab.ui.control.Button
        SatellitespositionanddelaysinfooutputfileLabel  matlab.ui.control.Label
        matCheckBox                     matlab.ui.control.CheckBox
        ReceiverpositionanderrorsoutputfileLabel  matlab.ui.control.Label
        txtCheckBox_2                   matlab.ui.control.CheckBox
        matCheckBox_2                   matlab.ui.control.CheckBox
        EditField2                      matlab.ui.control.EditField
        Button_6                        matlab.ui.control.Button
        GROUNDTRUTHPanel                matlab.ui.container.Panel
        XEditFieldLabel                 matlab.ui.control.Label
        XEditField                      matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        YEditField                      matlab.ui.control.NumericEditField
        ZEditFieldLabel                 matlab.ui.control.Label
        ZEditField                      matlab.ui.control.NumericEditField
        ReceivergroundtruthcoordinatesLabel  matlab.ui.control.Label
        mLabel                          matlab.ui.control.Label
        mLabel_2                        matlab.ui.control.Label
        mLabel_3                        matlab.ui.control.Label
        ELEVATIONMASKPanel              matlab.ui.container.Panel
        CutofangleLabel                 matlab.ui.control.Label
        EditField_3Label                matlab.ui.control.Label
        EditField_3                     matlab.ui.control.NumericEditField
        degreesLabel                    matlab.ui.control.Label
        MODELINGTab                     matlab.ui.container.Tab
        AtmosphericCorrectionsPanel     matlab.ui.container.Panel
        TroposphericCorrectionCheckBox  matlab.ui.control.CheckBox
        IonosphericCorrectionCheckBox   matlab.ui.control.CheckBox
        DropDown                        matlab.ui.control.DropDown
        DropDown_2                      matlab.ui.control.DropDown
        EstimateTroposhericWetPartCheckBox  matlab.ui.control.CheckBox
        AntennaCorrectionsPanel         matlab.ui.container.Panel
        SatelliteMassCentretoAntennaPhaseCentreOffsetCorrectionCheckBox  matlab.ui.control.CheckBox
        ReceiverAntennaPhaseCentreCorrectionCheckBox  matlab.ui.control.CheckBox
        TimeCorrectionsPanel            matlab.ui.container.Panel
        SatelliteClockOffsetCorrectionCheckBox  matlab.ui.control.CheckBox
        ConsiderSatelliteMovementDuringSignalFlightTimeCheckBox  matlab.ui.control.CheckBox
        ConsiderEarthRotationDuringSignalFlightTimeCheckBox  matlab.ui.control.CheckBox
        SolutionPanel                   matlab.ui.container.Panel
        LeastSquareAdjustmentCheckBox   matlab.ui.control.CheckBox
        RecursiveLeastSquareAdjustmentCheckBox  matlab.ui.control.CheckBox
        KalmanFilteringCheckBox         matlab.ui.control.CheckBox
        ANALYSISTab                     matlab.ui.container.Tab
        PLOTPOSITIONINGERRORSPanel      matlab.ui.container.Panel
        XYZPositioningErrorButton       matlab.ui.control.Button
        NEUPositioningErrorButton       matlab.ui.control.Button
        HorizontalPositioningErrorButton  matlab.ui.control.Button
        UpPositioningErrorButton        matlab.ui.control.Button
        DPositioningErrorButton         matlab.ui.control.Button
        MEANVALUESOFERRORSPanel         matlab.ui.container.Panel
        YmeanEditFieldLabel             matlab.ui.control.Label
        YmeanEditField                  matlab.ui.control.NumericEditField
        NmeanEditFieldLabel             matlab.ui.control.Label
        NmeanEditField                  matlab.ui.control.NumericEditField
        EmeanEditFieldLabel             matlab.ui.control.Label
        EmeanEditField                  matlab.ui.control.NumericEditField
        UmeanEditFieldLabel             matlab.ui.control.Label
        UmeanEditField                  matlab.ui.control.NumericEditField
        DerrormeanEditFieldLabel        matlab.ui.control.Label
        DerrormeanEditField             matlab.ui.control.NumericEditField
        HorizontalerrormeanEditFieldLabel  matlab.ui.control.Label
        HorizontalerrormeanEditField    matlab.ui.control.NumericEditField
        UperrormeanEditFieldLabel       matlab.ui.control.Label
        UperrormeanEditField            matlab.ui.control.NumericEditField
        ZmeanEditFieldLabel             matlab.ui.control.Label
        ZmeanEditField                  matlab.ui.control.NumericEditField
        XmeanEditFieldLabel             matlab.ui.control.Label
        XmeanEditField                  matlab.ui.control.NumericEditField
        UIAxes                          matlab.ui.control.UIAxes
        AugmentedStandartPointPositioningLabel  matlab.ui.control.Label
        augSPPLabel                     matlab.ui.control.Label
        RUNButton                       matlab.ui.control.Button
        ReadyLampLabel                  matlab.ui.control.Label
        ReadyLamp                       matlab.ui.control.Lamp
        DevelopedbyKbraATASOYFurkanGLTEKNLabel  matlab.ui.control.Label
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            imshow('eart.jpeg','Parent',app.UIAxes);
            app.UIFigure.Name = 'aug-SPP';
        end

        % Value changing function: ObsEditField
        function ObsEditFieldValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            [obs_file,~] = uigetfile({'*.*o'},'Select the OBSERVATION file');
            if obs_file~=0
                app.ObsEditField.Value = obs_file;
            else 
                app.ObsEditField.Value = '';
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            if app.BroadcastCheckBox.Value == 1
                [nav_file,~] = uigetfile('*.*n','Select the NAVIGATION file');
                if nav_file~=0
                    app.NavEditField.Value = nav_file;
                else 
                    app.NavEditField.Value = '';
                end
            elseif app.SP3CheckBox.Value == 1
                [sp3_file,~] = uigetfile('*.sp3','Select the SP3 file');
                if sp3_file~=0
                    app.NavEditField.Value = sp3_file;
                else 
                    app.NavEditField.Value = '';
                end
            end
        end

        % Value changed function: BroadcastCheckBox
        function BroadcastCheckBoxValueChanged(app, event)
            %value = app.BroadcastCheckBox.Value;
            if app.BroadcastCheckBox.Value == 1
                app.SP3CheckBox.Value = 0;
                app.RinexNavigationFileEditFieldLabel.Text = 'Rinex Navigation File :';
                app.NavEditField.Value = '';
                app.NavEditField.Enable = 'on';
                app.Button_2.Enable = 'on';
                %AntexFileCheckBoxValueChanged(app, event);
                app.AntexFileCheckBox.Value = 0;
                app.AntexFileCheckBox.Enable='off';
                app.EditField.Enable='off';
                app.EditField.Value='';
                app.Button_3.Enable = 'off';
                if app.BroadcastCheckBox_2.Value == 1
                    app.IonFileEditField.Enable = 'off';
                    app.Button_4.Enable = 'off';
                    app.IonFileEditField.Value = '';
                end
            else 
                app.AntexFileCheckBox.Enable='on';
                app.NavEditField.Value = '';
                app.NavEditField.Enable = 'off';
                app.Button_2.Enable = 'off';
                if app.BroadcastCheckBox_2.Value == 1
                    app.IonFileEditField.Enable = 'on';
                    app.Button_4.Enable = 'on';
                end
            end
        end

        % Value changed function: SP3CheckBox
        function SP3CheckBoxValueChanged(app, event)
            if app.SP3CheckBox.Value == 1
                app.BroadcastCheckBox.Value = 0;
                app.RinexNavigationFileEditFieldLabel.Text = 'SP3 File :';
                app.NavEditField.Value = '';
                app.NavEditField.Enable = 'on';
                app.AntexFileCheckBox.Enable='on';
                app.Button_2.Enable = 'on';
                if app.BroadcastCheckBox_2.Value == 1
                    app.IonFileEditField.Enable = 'on';
                    app.Button_4.Enable = 'on';
                end
            else 
                app.NavEditField.Value = '';
                app.NavEditField.Enable = 'off';
                app.Button_2.Enable = 'off';
            end
        end

        % Value changed function: AntexFileCheckBox
        function AntexFileCheckBoxValueChanged(app, event)
            %value = app.AntexFileCheckBox.Value;
            if app.AntexFileCheckBox.Value == 1
                app.EditField.Enable = 'on';
                app.Button_3.Enable = 'on';
            elseif app.AntexFileCheckBox.Value == 0
                app.EditField.Value = '';
                app.EditField.Enable = 'off';
                app.Button_3.Enable = 'off';
            end
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            [atx_file,~] = uigetfile('*.atx','Select the ANTEX file');
            if atx_file~=0
                app.EditField.Value = atx_file;
            else 
                app.EditField.Value = '';
            end
        end

        % Value changed function: BroadcastCheckBox_2
        function BroadcastCheckBox_2ValueChanged(app, event)
            if app.BroadcastCheckBox_2.Value == 1
                app.IONEXCheckBox.Value = 0;
                app.RinexNavigationFileEditField_2Label.Text = 'Rinex Navigation File :';
                app.IonFileEditField.Value = '';
                if app.BroadcastCheckBox.Value == 0
                    app.IonFileEditField.Enable = 'on';
                    app.Button_4.Enable = 'on';
                else
                    app.IonFileEditField.Enable = 'off';
                    app.Button_4.Enable = 'off';
                end
            else
                app.IonFileEditField.Enable = 'off';
                app.Button_4.Enable = 'off';
                app.IonFileEditField.Value = '';
            end
        end

        % Value changed function: IONEXCheckBox
        function IONEXCheckBoxValueChanged(app, event)
            if app.IONEXCheckBox.Value == 1
                app.BroadcastCheckBox_2.Value = 0;
                app.RinexNavigationFileEditField_2Label.Text = 'IONEX File :';
                app.IonFileEditField.Value = '';
                app.IonFileEditField.Enable = 'on';
                app.Button_4.Enable = 'on';
            else
                app.IonFileEditField.Enable = 'off';
                app.Button_4.Enable = 'off';
                app.IonFileEditField.Value = '';
            end
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            if app.BroadcastCheckBox_2.Value == 1
                [nav_file,~] = uigetfile('*.*n','Select the NAVIGATION file');
                if nav_file~=0
                    app.IonFileEditField.Value = nav_file;
                else 
                    app.IonFileEditField.Value = '';
                end
            elseif app.IONEXCheckBox.Value == 1
                [ionex_file,~] = uigetfile('*.*i','Select the IONEX file');
                if ionex_file~=0
                    app.IonFileEditField.Value = ionex_file;
                else 
                    app.IonFileEditField.Value = '';
                end
            end
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            dirname = uigetdir();
            dirname = dirname+"\sat_out.mat";
            app.EditField_2.Value = dirname;
          
        end

        % Value changed function: 
        % SatelliteClockOffsetCorrectionCheckBox
        function SatelliteClockOffsetCorrectionCheckBoxValueChanged(app, event)
            value = app.SatelliteClockOffsetCorrectionCheckBox.Value;
            
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            dirname = uigetdir();
            dirname = dirname+"\rec_out";
            app.EditField2.Value = dirname;
        end

        % Value changed function: matCheckBox
        function matCheckBoxValueChanged(app, event)
            value = app.matCheckBox.Value;
            if app.matCheckBox.Value==1
                app.EditField_2.Enable = 'on';
                app.Button_5.Enable = 'on';
            else
                app.EditField_2.Enable = 'off';
                app.Button_5.Enable = 'off';
                app.EditField_2.Value = '';
            end
        end

        % Value changed function: txtCheckBox_2
        function txtCheckBox_2ValueChanged(app, event)
            value = app.txtCheckBox_2.Value;
            if app.txtCheckBox_2.Value==1 || app.matCheckBox_2.Value==1
                app.EditField2.Enable = 'on';
                app.Button_6.Enable = 'on';
            else
                app.EditField2.Enable = 'off';
                app.Button_6.Enable = 'off';
                app.EditField2.Value = '';
            end
        end

        % Value changed function: matCheckBox_2
        function matCheckBox_2ValueChanged(app, event)
            value = app.matCheckBox_2.Value;
            if app.txtCheckBox_2.Value==1 || app.matCheckBox_2.Value==1
                app.EditField2.Enable = 'on';
                app.Button_6.Enable = 'on';
            else
                app.EditField2.Enable = 'off';
                app.Button_6.Enable = 'off';
                app.EditField2.Value = '';
            end
        end

        % Value changed function: IonosphericCorrectionCheckBox
        function IonosphericCorrectionCheckBoxValueChanged(app, event)
            value = app.IonosphericCorrectionCheckBox.Value;
            if value == 1
                app.DropDown.Enable='on';
            else
                app.DropDown.Enable='off';
            end
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            global all coor spp
            [options] = check_gui(app);
            app.RUNButton.Enable = 'off';
            app.ReadyLampLabel.Text = 'Running...';
            app.ReadyLamp.Color = [1 0 0];
            pause(0.1);
            [ all_info,coor_rec,spp_info ] = Main( options );
            [all_means] = error_means(coor_rec);
            save_output(all_info,coor_rec,spp_info,options);
            app.XmeanEditField.Value = all_means(1);app.YmeanEditField.Value = all_means(2);app.ZmeanEditField.Value = all_means(3);
            app.NmeanEditField.Value = all_means(4);app.EmeanEditField.Value = all_means(5);app.UmeanEditField.Value = all_means(6);
            app.DerrormeanEditField.Value = all_means(7);app.HorizontalerrormeanEditField.Value = all_means(8);
            app.UperrormeanEditField.Value = all_means(6);
            all = all_info; coor=coor_rec;spp = spp_info;
            app.ReadyLamp.Color = [0 1 0];
            app.ReadyLampLabel.Text = 'Ready...';
            app.RUNButton.Enable = 'on';
        end

        % Value changed function: TroposphericCorrectionCheckBox
        function TroposphericCorrectionCheckBoxValueChanged(app, event)
            value = app.TroposphericCorrectionCheckBox.Value;
            if value
                app.DropDown_2.Enable = 'on';
            else
                app.DropDown_2.Enable = 'off';
            end
        end

        % Button pushed function: XYZPositioningErrorButton
        function XYZPositioningErrorButtonPushed(app, event)
            global coor 
            ep= coor(:,1);
            X = coor(:,6); Y = coor(:,7); Z = coor(:,8); 
            figure;
            p = plot(ep,X,ep,Y,ep,Z);
            p(2).Marker = '.';p(1).Marker = '.';p(3).Marker = '.';
            title('XYZ Positioning Error');
            xlabel('Time (s)');ylabel('Error (m)');
            legend({'X error','Y error','Z error'},'Location','northeast');
        end

        % Button pushed function: NEUPositioningErrorButton
        function NEUPositioningErrorButtonPushed(app, event)
            global coor 
            ep= 1:length(coor);
            N = coor(:,9); E = coor(:,10); U = coor(:,11); 
            figure;
            p = plot(ep,N,ep,E,ep,U);
            p(2).Marker = '.';p(1).Marker = '.';p(3).Marker = '.';
            title('NEU Positioning Error');
            xlabel('Time (s)');ylabel('Error (m)');
            legend({'N error','E error','U error'},'Location','northeast');            
        end

        % Button pushed function: HorizontalPositioningErrorButton
        function HorizontalPositioningErrorButtonPushed(app, event)
            global coor 
            N = coor(:,9); E = coor(:,10);
            figure;
            plot(N,E, 'b.','MarkerSize',10);
            title('Horizontal Positioning Error');
            xlabel('E error (m)');ylabel('N error (m)');
            legend({'Horizontal Error'},'Location','northeast');
        end

        % Button pushed function: UpPositioningErrorButton
        function UpPositioningErrorButtonPushed(app, event)
            global coor 
            U = coor(:,11); 
            figure;
            p = plot(U);
            p(1).Marker = '.';
            title('Up Positioning Error');
            xlabel('Time (s)');ylabel('Up error (m)');
            legend({'UP Error'},'Location','northeast');
        end

        % Button pushed function: DPositioningErrorButton
        function DPositioningErrorButtonPushed(app, event)
            global coor 
            TH_D = coor(:,12); 
            figure;
            p=plot(TH_D);
            p(1).Marker = '.';
            title('3D Positioning Error');
            xlabel('Time (s)');ylabel('3D error (m)');
            legend({'3D Error'},'Location','northeast');            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.INPUTOUTPUTFILESTab = uitab(app.TabGroup);
            app.INPUTOUTPUTFILESTab.Title = '                 INPUT / OUTPUT FILES                    ';
            app.INPUTFILESPanel = uipanel(app.INPUTOUTPUTFILESTab);
            app.INPUTFILESPanel.Title = 'INPUT FILES';
            app.Button = uibutton(app.INPUTFILESPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '...';
            app.RinexObservationFileEditFieldLabel = uilabel(app.INPUTFILESPanel);
            app.RinexObservationFileEditFieldLabel.Text = 'Rinex Observation File :';
            app.ObsEditField = uieditfield(app.INPUTFILESPanel, 'text');
            app.ObsEditField.ValueChangingFcn = createCallbackFcn(app, @ObsEditFieldValueChanging, true);
            app.OrbitfileButtonGroup = uibuttongroup(app.INPUTFILESPanel);
            app.OrbitfileButtonGroup.Title = 'Orbit file  :';
            app.Button_2 = uibutton(app.OrbitfileButtonGroup, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Enable = 'off';
            app.Button_2.Text = '...';
            app.RinexNavigationFileEditFieldLabel = uilabel(app.OrbitfileButtonGroup);
            app.RinexNavigationFileEditFieldLabel.Text = 'Rinex Navigation File :';
            app.NavEditField = uieditfield(app.OrbitfileButtonGroup, 'text');
            app.NavEditField.Enable = 'off';
            app.BroadcastCheckBox = uicheckbox(app.OrbitfileButtonGroup);
            app.BroadcastCheckBox.ValueChangedFcn = createCallbackFcn(app, @BroadcastCheckBoxValueChanged, true);
            app.BroadcastCheckBox.Text = 'Broadcast';
            app.SP3CheckBox = uicheckbox(app.OrbitfileButtonGroup);
            app.SP3CheckBox.ValueChangedFcn = createCallbackFcn(app, @SP3CheckBoxValueChanged, true);
            app.SP3CheckBox.Text = 'SP3';
            app.AntexFileCheckBox = uicheckbox(app.INPUTFILESPanel);
            app.AntexFileCheckBox.ValueChangedFcn = createCallbackFcn(app, @AntexFileCheckBoxValueChanged, true);
            app.AntexFileCheckBox.Text = 'Antex File : ';
            app.Label = uilabel(app.INPUTFILESPanel);
            app.Label.Enable = 'off';
            app.Label.Text = '';
            app.EditField = uieditfield(app.INPUTFILESPanel, 'text');
            app.EditField.Enable = 'off';
            app.Button_3 = uibutton(app.INPUTFILESPanel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Enable = 'off';
            app.Button_3.Text = '...';
            app.IonosphereSourceButtonGroup = uibuttongroup(app.INPUTFILESPanel);
            app.IonosphereSourceButtonGroup.Title = 'Ionosphere Source :';
            app.RinexNavigationFileEditField_2Label = uilabel(app.IonosphereSourceButtonGroup);
            app.RinexNavigationFileEditField_2Label.Text = 'Rinex Navigation File :';
            app.IonFileEditField = uieditfield(app.IonosphereSourceButtonGroup, 'text');
            app.IonFileEditField.Enable = 'off';
            app.Button_4 = uibutton(app.IonosphereSourceButtonGroup, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Enable = 'off';
            app.Button_4.Text = '...';
            app.BroadcastCheckBox_2 = uicheckbox(app.IonosphereSourceButtonGroup);
            app.BroadcastCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @BroadcastCheckBox_2ValueChanged, true);
            app.BroadcastCheckBox_2.Text = 'Broadcast';
            app.IONEXCheckBox = uicheckbox(app.IonosphereSourceButtonGroup);
            app.IONEXCheckBox.ValueChangedFcn = createCallbackFcn(app, @IONEXCheckBoxValueChanged, true);
            app.IONEXCheckBox.Text = 'IONEX';
            app.OUTPUTFILESPanel = uipanel(app.INPUTOUTPUTFILESTab);
            app.OUTPUTFILESPanel.Title = 'OUTPUT FILES';
            app.EditField_2 = uieditfield(app.OUTPUTFILESPanel, 'text');
            app.EditField_2.Enable = 'off';
            app.Button_5 = uibutton(app.OUTPUTFILESPanel, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Enable = 'off';
            app.Button_5.Text = '...';
            app.SatellitespositionanddelaysinfooutputfileLabel = uilabel(app.OUTPUTFILESPanel);
            app.SatellitespositionanddelaysinfooutputfileLabel.Text = 'Satellites position and delays info output file : ';
            app.matCheckBox = uicheckbox(app.OUTPUTFILESPanel);
            app.matCheckBox.ValueChangedFcn = createCallbackFcn(app, @matCheckBoxValueChanged, true);
            app.matCheckBox.Text = '.mat';
            app.ReceiverpositionanderrorsoutputfileLabel = uilabel(app.OUTPUTFILESPanel);
            app.ReceiverpositionanderrorsoutputfileLabel.Text = 'Receiver position and errors output file : ';
            app.txtCheckBox_2 = uicheckbox(app.OUTPUTFILESPanel);
            app.txtCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @txtCheckBox_2ValueChanged, true);
            app.txtCheckBox_2.Text = '.txt';
            app.matCheckBox_2 = uicheckbox(app.OUTPUTFILESPanel);
            app.matCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @matCheckBox_2ValueChanged, true);
            app.matCheckBox_2.Text = '.mat';
            app.EditField2 = uieditfield(app.OUTPUTFILESPanel, 'text');
            app.EditField2.Enable = 'off';
            app.Button_6 = uibutton(app.OUTPUTFILESPanel, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Enable = 'off';
            app.Button_6.Text = '...';
            app.GROUNDTRUTHPanel = uipanel(app.INPUTOUTPUTFILESTab);
            app.GROUNDTRUTHPanel.Title = 'GROUND TRUTH';
            app.XEditFieldLabel = uilabel(app.GROUNDTRUTHPanel);
            app.XEditFieldLabel.Text = 'X :';
            app.XEditField = uieditfield(app.GROUNDTRUTHPanel, 'numeric');
            app.YEditFieldLabel = uilabel(app.GROUNDTRUTHPanel);
            app.YEditFieldLabel.Text = 'Y :';
            app.YEditField = uieditfield(app.GROUNDTRUTHPanel, 'numeric');
            app.ZEditFieldLabel = uilabel(app.GROUNDTRUTHPanel);
            app.ZEditFieldLabel.Text = 'Z :';
            app.ZEditField = uieditfield(app.GROUNDTRUTHPanel, 'numeric');
            app.ReceivergroundtruthcoordinatesLabel = uilabel(app.GROUNDTRUTHPanel);
            app.ReceivergroundtruthcoordinatesLabel.Text = 'Receiver ground truth coordinates :';
            app.mLabel = uilabel(app.GROUNDTRUTHPanel);
            app.mLabel.Text = '(m)';
            app.mLabel_2 = uilabel(app.GROUNDTRUTHPanel);
            app.mLabel_2.Text = '(m)';
            app.mLabel_3 = uilabel(app.GROUNDTRUTHPanel);
            app.mLabel_3.Text = '(m)';
            app.ELEVATIONMASKPanel = uipanel(app.INPUTOUTPUTFILESTab);
            app.ELEVATIONMASKPanel.Title = 'ELEVATION MASK';
            app.CutofangleLabel = uilabel(app.ELEVATIONMASKPanel);
            app.CutofangleLabel.Text = 'Cut of angle : ';
            app.EditField_3Label = uilabel(app.ELEVATIONMASKPanel);
            app.EditField_3Label.Text = '';
            app.EditField_3 = uieditfield(app.ELEVATIONMASKPanel, 'numeric');
            app.EditField_3.Limits = [0 90];
            app.degreesLabel = uilabel(app.ELEVATIONMASKPanel);
            app.degreesLabel.Text = '(degrees)';
            app.MODELINGTab = uitab(app.TabGroup);
            app.MODELINGTab.Title = '                             MODELING                               ';
            app.AtmosphericCorrectionsPanel = uipanel(app.MODELINGTab);
            app.AtmosphericCorrectionsPanel.Title = 'Atmospheric Corrections';
            app.TroposphericCorrectionCheckBox = uicheckbox(app.AtmosphericCorrectionsPanel);
            app.TroposphericCorrectionCheckBox.ValueChangedFcn = createCallbackFcn(app, @TroposphericCorrectionCheckBoxValueChanged, true);
            app.TroposphericCorrectionCheckBox.Text = 'Tropospheric Correction';
            app.IonosphericCorrectionCheckBox = uicheckbox(app.AtmosphericCorrectionsPanel);
            app.IonosphericCorrectionCheckBox.ValueChangedFcn = createCallbackFcn(app, @IonosphericCorrectionCheckBoxValueChanged, true);
            app.IonosphericCorrectionCheckBox.Text = 'Ionospheric Correction';
            app.DropDown = uidropdown(app.AtmosphericCorrectionsPanel);
            app.DropDown.Items = {'Klobuchar', 'IONEX'};
            app.DropDown.Enable = 'off';
            app.DropDown.Value = 'Klobuchar';
            app.DropDown_2 = uidropdown(app.AtmosphericCorrectionsPanel);
            app.DropDown_2.Items = {'Saastamoinen'};
            app.DropDown_2.Enable = 'off';
            app.DropDown_2.Value = 'Saastamoinen';
            app.EstimateTroposhericWetPartCheckBox = uicheckbox(app.AtmosphericCorrectionsPanel);
            app.EstimateTroposhericWetPartCheckBox.Enable = 'off';
            app.EstimateTroposhericWetPartCheckBox.Text = 'Estimate Troposheric Wet Part ';
            app.AntennaCorrectionsPanel = uipanel(app.MODELINGTab);
            app.AntennaCorrectionsPanel.Title = 'Antenna Corrections';
            app.SatelliteMassCentretoAntennaPhaseCentreOffsetCorrectionCheckBox = uicheckbox(app.AntennaCorrectionsPanel);
            app.SatelliteMassCentretoAntennaPhaseCentreOffsetCorrectionCheckBox.Text = 'Satellite Mass Centre to Antenna Phase Centre Offset Correction';
            app.ReceiverAntennaPhaseCentreCorrectionCheckBox = uicheckbox(app.AntennaCorrectionsPanel);
            app.ReceiverAntennaPhaseCentreCorrectionCheckBox.Enable = 'off';
            app.ReceiverAntennaPhaseCentreCorrectionCheckBox.Text = 'Receiver Antenna Phase Centre Correction';
            app.ReceiverAntennaPhaseCentreCorrectionCheckBox.Value = true;
            app.TimeCorrectionsPanel = uipanel(app.MODELINGTab);
            app.TimeCorrectionsPanel.Title = 'Time Corrections';
            app.SatelliteClockOffsetCorrectionCheckBox = uicheckbox(app.TimeCorrectionsPanel);
            app.SatelliteClockOffsetCorrectionCheckBox.ValueChangedFcn = createCallbackFcn(app, @SatelliteClockOffsetCorrectionCheckBoxValueChanged, true);
            app.SatelliteClockOffsetCorrectionCheckBox.Enable = 'off';
            app.SatelliteClockOffsetCorrectionCheckBox.Text = 'Satellite Clock Offset Correction';
            app.SatelliteClockOffsetCorrectionCheckBox.Value = true;
            app.ConsiderSatelliteMovementDuringSignalFlightTimeCheckBox = uicheckbox(app.TimeCorrectionsPanel);
            app.ConsiderSatelliteMovementDuringSignalFlightTimeCheckBox.Enable = 'off';
            app.ConsiderSatelliteMovementDuringSignalFlightTimeCheckBox.Text = 'Consider Satellite Movement During Signal Flight Time';
            app.ConsiderSatelliteMovementDuringSignalFlightTimeCheckBox.Value = true;
            app.ConsiderEarthRotationDuringSignalFlightTimeCheckBox = uicheckbox(app.TimeCorrectionsPanel);
            app.ConsiderEarthRotationDuringSignalFlightTimeCheckBox.Enable = 'off';
            app.ConsiderEarthRotationDuringSignalFlightTimeCheckBox.Text = 'Consider Earth Rotation During Signal Flight Time';
            app.ConsiderEarthRotationDuringSignalFlightTimeCheckBox.Value = true;
            app.SolutionPanel = uipanel(app.MODELINGTab);
            app.SolutionPanel.Title = 'Solution';
            app.LeastSquareAdjustmentCheckBox = uicheckbox(app.SolutionPanel);
            app.LeastSquareAdjustmentCheckBox.Text = 'Least Square Adjustment';
            app.LeastSquareAdjustmentCheckBox.Value = true;
            app.RecursiveLeastSquareAdjustmentCheckBox = uicheckbox(app.SolutionPanel);
            app.RecursiveLeastSquareAdjustmentCheckBox.Enable = 'off';
            app.RecursiveLeastSquareAdjustmentCheckBox.Text = 'Recursive Least Square Adjustment';
            app.KalmanFilteringCheckBox = uicheckbox(app.SolutionPanel);
            app.KalmanFilteringCheckBox.Enable = 'off';
            app.KalmanFilteringCheckBox.Text = 'Kalman Filtering';
            app.ANALYSISTab = uitab(app.TabGroup);
            app.ANALYSISTab.Title = '                              ANALYSIS                        ';
            app.PLOTPOSITIONINGERRORSPanel = uipanel(app.ANALYSISTab);
            app.PLOTPOSITIONINGERRORSPanel.Title = 'PLOT POSITIONING ERRORS';
            app.XYZPositioningErrorButton = uibutton(app.PLOTPOSITIONINGERRORSPanel, 'push');
            app.XYZPositioningErrorButton.ButtonPushedFcn = createCallbackFcn(app, @XYZPositioningErrorButtonPushed, true);
            app.XYZPositioningErrorButton.Text = 'XYZ Positioning Error';
            app.NEUPositioningErrorButton = uibutton(app.PLOTPOSITIONINGERRORSPanel, 'push');
            app.NEUPositioningErrorButton.ButtonPushedFcn = createCallbackFcn(app, @NEUPositioningErrorButtonPushed, true);
            app.NEUPositioningErrorButton.Text = 'NEU Positioning Error';
            app.HorizontalPositioningErrorButton = uibutton(app.PLOTPOSITIONINGERRORSPanel, 'push');
            app.HorizontalPositioningErrorButton.ButtonPushedFcn = createCallbackFcn(app, @HorizontalPositioningErrorButtonPushed, true);
            app.HorizontalPositioningErrorButton.Text = 'Horizontal Positioning Error';
            app.UpPositioningErrorButton = uibutton(app.PLOTPOSITIONINGERRORSPanel, 'push');
            app.UpPositioningErrorButton.ButtonPushedFcn = createCallbackFcn(app, @UpPositioningErrorButtonPushed, true);
            app.UpPositioningErrorButton.Text = 'Up Positioning Error';
            app.DPositioningErrorButton = uibutton(app.PLOTPOSITIONINGERRORSPanel, 'push');
            app.DPositioningErrorButton.ButtonPushedFcn = createCallbackFcn(app, @DPositioningErrorButtonPushed, true);
            app.DPositioningErrorButton.Text = ' 3D Positioning Error';
            app.MEANVALUESOFERRORSPanel = uipanel(app.ANALYSISTab);
            app.MEANVALUESOFERRORSPanel.Title = 'MEAN VALUES OF ERRORS';
            app.YmeanEditFieldLabel = uilabel(app.MEANVALUESOFERRORSPanel);
            app.YmeanEditFieldLabel.Text = 'Y mean :';
            app.YmeanEditField = uieditfield(app.MEANVALUESOFERRORSPanel, 'numeric');
            app.YmeanEditField.Enable = 'off';
            app.NmeanEditFieldLabel = uilabel(app.MEANVALUESOFERRORSPanel);
            app.NmeanEditFieldLabel.Text = 'N mean :';
            app.NmeanEditField = uieditfield(app.MEANVALUESOFERRORSPanel, 'numeric');
            app.NmeanEditField.Enable = 'off';
            app.EmeanEditFieldLabel = uilabel(app.MEANVALUESOFERRORSPanel);
            app.EmeanEditFieldLabel.Text = 'E mean :';
            app.EmeanEditField = uieditfield(app.MEANVALUESOFERRORSPanel, 'numeric');
            app.EmeanEditField.Enable = 'off';
            app.UmeanEditFieldLabel = uilabel(app.MEANVALUESOFERRORSPanel);
            app.UmeanEditFieldLabel.Text = 'U mean :';
            app.UmeanEditField = uieditfield(app.MEANVALUESOFERRORSPanel, 'numeric');
            app.UmeanEditField.Enable = 'off';
            app.DerrormeanEditFieldLabel = uilabel(app.MEANVALUESOFERRORSPanel);
            app.DerrormeanEditFieldLabel.Text = '3D error mean :  ';
            app.DerrormeanEditField = uieditfield(app.MEANVALUESOFERRORSPanel, 'numeric');
            app.DerrormeanEditField.ValueDisplayFormat = '%.5f';
            app.DerrormeanEditField.Enable = 'off';
            app.HorizontalerrormeanEditFieldLabel = uilabel(app.MEANVALUESOFERRORSPanel);
            app.HorizontalerrormeanEditFieldLabel.Text = 'Horizontal error mean : ';
            app.HorizontalerrormeanEditField = uieditfield(app.MEANVALUESOFERRORSPanel, 'numeric');
            app.HorizontalerrormeanEditField.Enable = 'off';
            app.UperrormeanEditFieldLabel = uilabel(app.MEANVALUESOFERRORSPanel);
            app.UperrormeanEditFieldLabel.Text = 'Up error mean';
            app.UperrormeanEditField = uieditfield(app.MEANVALUESOFERRORSPanel, 'numeric');
            app.UperrormeanEditField.Enable = 'off';
            app.ZmeanEditFieldLabel = uilabel(app.MEANVALUESOFERRORSPanel);
            app.ZmeanEditFieldLabel.Text = 'Z  mean:';
            app.ZmeanEditField = uieditfield(app.MEANVALUESOFERRORSPanel, 'numeric');
            app.ZmeanEditField.Enable = 'off';
            app.XmeanEditFieldLabel = uilabel(app.MEANVALUESOFERRORSPanel);
            app.XmeanEditFieldLabel.Text = 'X mean :';
            app.XmeanEditField = uieditfield(app.MEANVALUESOFERRORSPanel, 'numeric');
            app.XmeanEditField.Enable = 'off';
            app.UIAxes = uiaxes(app.UIFigure);
            app.AugmentedStandartPointPositioningLabel = uilabel(app.UIFigure);
            app.AugmentedStandartPointPositioningLabel.Text = 'Augmented Standart Point Positioning';
            app.augSPPLabel = uilabel(app.UIFigure);
            app.augSPPLabel.Text = 'aug-SPP';
            app.RUNButton = uibutton(app.UIFigure, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN ';
            app.ReadyLampLabel = uilabel(app.UIFigure);
            app.ReadyLampLabel.Text = 'Ready...';
            app.ReadyLamp = uilamp(app.UIFigure);
            app.DevelopedbyKbraATASOYFurkanGLTEKNLabel = uilabel(app.UIFigure);
            app.DevelopedbyKbraATASOYFurkanGLTEKNLabel.Text = 'Developed by Kübra ATASOY  & Furkan GÜLTEKİN ';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end